classdef frontendapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        DepartmentDropDownLabel     matlab.ui.control.Label
        DepartmentDropDown          matlab.ui.control.DropDown
        ShoppingListListBoxLabel    matlab.ui.control.Label
        ShoppingListListBox         matlab.ui.control.ListBox
        AddButton                   matlab.ui.control.Button
        RemoveButton                matlab.ui.control.Button
        PotentialItemsListBoxLabel  matlab.ui.control.Label
        PotentialItemsListBox       matlab.ui.control.ListBox
        MapResultsButton            matlab.ui.control.Button
        ContextMenu                 matlab.ui.container.ContextMenu
    end

    
    properties (Access = private)
        shoppingArray; % Description
        removeElements; % Description
        i; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.shoppingArray = [app.shoppingArray,app.PotentialItemsListBox.Value];
            app.shoppingArray = unique(app.shoppingArray);
            app.ShoppingListListBox.Items = app.shoppingArray;
        end

        % Value changed function: DepartmentDropDown
        function DepartmentDropDownValueChanged(app, event)
            value = app.DepartmentDropDown.Value;
            if value == ["Fruits/Vegetables"]
                app.PotentialItemsListBox.Items = {'Apples','Oranges','Lettuce','Carrot','Eggplant'};
            elseif value == ["Bakery"]
                app.PotentialItemsListBox.Items = {'French Load','Donuts','Pastries','Burger Buns'};
            elseif value == ["Soups/Canned"]
                app.PotentialItemsListBox.Items = {'Tomato', 'Chicken Noodle','Hot and Sour','Tomato Sauce'};
            elseif value == ["Dairy/Eggs/Cheese"]
                app.PotentialItemsListBox.Items = {'Milk','Eggs','Cheese','Cream Cheese'};
            elseif value == ["Beverages"]
                app.PotentialItemsListBox.Items = {'Soda','Water','Coffee','Orange Juice'};
            elseif value == ["Deli"]
                app.PotentialItemsListBox.Items = {'Sandwhich','Chips','Candy','Pasta'};
            elseif value == ["Meat/Seafood"]
                app.PotentialItemsListBox.Items = {'Steak','Pork','Chicken','Fish','Shrimp'};
            else
                app.PotentialItemsListBox.Items = {};
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            for i = 1:size(app.ShoppingListListBox.Value)
                app.removeElements = find(strcmp(app.ShoppingListListBox.Value,app.shoppingArray));          
                app.shoppingArray(app.removeElements()) = [];        
                app.shoppingArray = unique(app.shoppingArray); 
                app.ShoppingListListBox.Items = app.shoppingArray;
            end
            
        end

        % Button pushed function: MapResultsButton
        function MapResultsButtonPushed(app, event)
            fileID=fopen('listOutput.txt','w')
            for i=1:size(app.shoppingArray)
                fprintf(fileID,'%s\n',app.shoppingArray{i,:});
            end
            fclose(fileID);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.DepartmentDropDownLabel = uilabel(app.Panel);
            app.DepartmentDropDownLabel.Visible = 'off';
            app.DepartmentDropDownLabel.Text = 'Department';
            app.DepartmentDropDown = uidropdown(app.Panel);
            app.DepartmentDropDown.Items = {'Select One', 'Fruits/Vegetables', 'Bakery', 'Soups/Canned', 'Dairy/Eggs/Cheese', 'Beverages', 'Deli', 'Meat/Seafood'};
            app.DepartmentDropDown.ValueChangedFcn = createCallbackFcn(app, @DepartmentDropDownValueChanged, true);
            app.DepartmentDropDown.Value = 'Select One';
            app.ShoppingListListBoxLabel = uilabel(app.Panel);
            app.ShoppingListListBoxLabel.Text = 'Shopping List';
            app.ShoppingListListBox = uilistbox(app.Panel);
            app.ShoppingListListBox.Items = {};
            app.ShoppingListListBox.Multiselect = 'on';
            app.ShoppingListListBox.Value = {};
            app.AddButton = uibutton(app.Panel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.Panel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.PotentialItemsListBoxLabel = uilabel(app.Panel);
            app.PotentialItemsListBoxLabel.Text = 'Potential Items';
            app.PotentialItemsListBox = uilistbox(app.Panel);
            app.PotentialItemsListBox.Items = {};
            app.PotentialItemsListBox.Multiselect = 'on';
            app.PotentialItemsListBox.Value = {};
            app.MapResultsButton = uibutton(app.Panel, 'push');
            app.MapResultsButton.ButtonPushedFcn = createCallbackFcn(app, @MapResultsButtonPushed, true);
            app.MapResultsButton.Text = 'Map Results';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.PotentialItemsListBox.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = frontendapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end